classdef Recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        AudioReadMenu                matlab.ui.container.Menu
        AudiowriteMenu               matlab.ui.container.Menu
        InportCalibrationDataMenu    matlab.ui.container.Menu
        ExportCalibrationDataMenu    matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        VADsettingMenu               matlab.ui.container.Menu
        ThresholdMenu                matlab.ui.container.Menu
        CalibrationMenu              matlab.ui.container.Menu
        SettingMenu                  matlab.ui.container.Menu
        CalibrationstartMenu         matlab.ui.container.Menu
        Title_2                      matlab.ui.container.Panel
        EndtimeEditField             matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel        matlab.ui.control.Label
        StarttimeEditField           matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        Panel_2                      matlab.ui.container.Panel
        VADButton                    matlab.ui.control.Button
        TimeLabel                    matlab.ui.control.Label
        RecordButton                 matlab.ui.control.Button
        Lamp_Rec                     matlab.ui.control.Lamp
        StopButton                   matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        Panel_3                      matlab.ui.container.Panel
        Lamp_11                      matlab.ui.control.Lamp
        Lamp_10                      matlab.ui.control.Lamp
        Lamp_9                       matlab.ui.control.Lamp
        Lamp_8                       matlab.ui.control.Lamp
        Lamp_7                       matlab.ui.control.Lamp
        Lamp_6                       matlab.ui.control.Lamp
        Lamp_5                       matlab.ui.control.Lamp
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        Lamp                         matlab.ui.control.Lamp
        Title                        matlab.ui.container.Panel
        AudioOUTDropDown             matlab.ui.control.DropDown
        AudioOUTDropDownLabel        matlab.ui.control.Label
        CreatedbyTakutoISOYAMALabel  matlab.ui.control.Label
        UnokiLABLabel                matlab.ui.control.Label
        AudioINDropDown              matlab.ui.control.DropDown
        AudioINDropDownLabel         matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        RecordingLabel               matlab.ui.control.Label
    end

    properties (Access = private)
        CallingApp % Main app
        %
        t
        T
        nBits = 16;
        %
        Inputdevices
        Outputdevices
        DeviceReader
        DeviceWriter
        Buffer = 8820;
        StopFlag = 0;
        %
        InputID
        OutputID
        CalibAmp
        CalibdB = 94;
        recObj
        %
        Filename
        Filepath
        StartPoint
        EndPoint
        STime
        ETime
        Threshold = -20;
        VADXt
        VADt
        PlayFlag = 0;
    end

    properties (Access = public)
        Xt
        Fs = 44100;
        CalibrationFactor = 1;
        SPL = 100;
        player
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            % Device Input
            app.DeviceReader = audioDeviceReader(app.Fs,app.Buffer);
            InD = getAudioDevices(app.DeviceReader);
            app.AudioINDropDown.Items = InD;
            app.InputID = -1;
            % Device Output
            app.DeviceWriter = audioDeviceWriter(app.Fs,'BufferSize',app.Buffer);
            OutID = getAudioDevices(app.DeviceWriter);
            app.AudioOUTDropDown.Items = OutID;
            app.OutputID = -1;

            app.AudiowriteMenu.Enable = 0;
            app.PlayButton.Enable = 0;
            app.VADButton.Enable = 0;
            app.StopButton.Enable = 0;
            app.StarttimeEditField.Enable = 0;
            app.StarttimeEditFieldLabel.Enable = 0;
            app.EndtimeEditField.Enable = 0;
            app.EndtimeEditFieldLabel.Enable = 0;
        end

        % Menu selected function: AudioReadMenu
        function AudioReadMenuSelected(app, event)
            [app.Filename,app.Filepath] = uigetfile({'*.wav';'*.mp3';'*.m4a';'*.mp4';'*.aifc';...
                '*.aiff';'*.aif';'*.au';'*.flac';'*.ogg';'*.opus'},'File Selector');
            if app.Filename ~= 0
                disp([app.Filepath app.Filename])
                [app.Xt,app.Fs] = audioread([app.Filepath app.Filename]);
                app.Xt = resample(app.Xt,44100,app.Fs);
                app.Fs = 44100;
                app.t = (0:length(app.Xt)-1)/app.Fs;
                UpdatePlot(app.CallingApp, app.Xt, app.Fs, app.t, app.Filename);
                app.AudiowriteMenu.Enable = 1;
                app.PlayButton.Enable = 1;
                plot(app.UIAxes,app.t,app.Xt)

                app.StartPoint = 0;
                app.EndPoint = length(app.Xt);
                app.STime = app.t(1);
                app.ETime = app.t(end);

                app.VADButton.Enable = 1;
                app.StopButton.Enable = 0;
                app.StarttimeEditField.Enable = 1;
                app.StarttimeEditFieldLabel.Enable = 1;
                app.EndtimeEditField.Enable = 1;
                app.EndtimeEditFieldLabel.Enable = 1;
            else
                uialert(app.UIFigure,'Please input file name','No file name')
            end
        end

        % Menu selected function: AudiowriteMenu
        function AudiowriteMenuSelected(app, event)
            [app.Filename,app.Filepath] = uiputfile('*.wav','Audio write','Output.wav');
            if app.Filename ~= 0
                % PopUp
                d = uiprogressdlg(app.UIFigure,'Title','Audio Write','Indeterminate','on');
                drawnow
                audiowrite([app.Filepath app.Filename],app.Xt(app.StartPoint+1:app.EndPoint),app.Fs);
                close(d)
                app.VADt = (0:length(app.Xt(app.StartPoint+1:app.EndPoint))-1)/app.Fs;
                UpdatePlot(app.CallingApp, app.Xt(app.StartPoint+1:app.EndPoint), app.Fs, app.VADt, app.Filename);
            else
                uialert(app.UIFigure,'Please input file name','No file name')
            end
        end

        % Menu selected function: InportCalibrationDataMenu
        function InportCalibrationDataMenuSelected(app, event)
            [app.Filename,app.Filepath] = uigetfile({'*.mat'},'File Selector');
            if app.Filename ~= 0
                disp([app.Filepath app.Filename])
                load([app.Filepath app.Filename],'CFactor')
                app.CalibrationFactor = CFactor;
            else
                uialert(app.UIFigure,'Please input file name','No file name')
            end
        end

        % Menu selected function: ExportCalibrationDataMenu
        function ExportCalibrationDataMenuSelected(app, event)
            [app.Filename,app.Filepath] = uiputfile('*.mat','Export Calibration Data','CalibrationData.mat');
            if app.Filename ~= 0
                % PopUp
                CFactor = app.CalibrationFactor;
                d = uiprogressdlg(app.UIFigure,'Title','Audio Write','Indeterminate','on');
                drawnow
                save([app.Filepath app.Filename],'CFactor');
                close(d)
            else
                uialert(app.UIFigure,'Please input file name','No file name')
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: ThresholdMenu
        function ThresholdMenuSelected(app, event)
            prompt = {'Enter the VAD threshold value. (Default: -20 dB)'};
            dlgtitle = 'Threshold Value';
            fieldsize = [1 40];
            definput = {num2str(app.Threshold)};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            app.Threshold = str2double(answer);
        end

        % Menu selected function: SettingMenu
        function SettingMenuSelected(app, event)
            prompt = {'What is the sound pressure level of the pure tone? (Default: 94 dB)'};
            dlgtitle = 'Calibration SPL';
            fieldsize = [1 40];
            definput = {'94'};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            app.CalibdB = str2double(answer);
        end

        % Menu selected function: CalibrationstartMenu
        function CalibrationstartMenuSelected(app, event)
            CalibrationFlag = 0;
            answer = questdlg(...
                compose(['Calibration Method\n' ...
                'Calibrate with ' num2str(app.CalibdB) ' dB of pure tone at 1 kHz.\n' ...
                'Calibration takes 10 s.\n' ...
                'Press "Yes" after the tone is sounded.\n' ...
                'If you do not want to calibrate, press "No".']), ...
                'Calibration','Yes','No','No');
            % Handle response
            switch answer
                case 'Yes'
                    disp([answer ' coming right up.'])
                    CalibrationFlag = 1;
                case 'No'
                    disp([answer ' coming right up.'])
                    CalibrationFlag = 0;
            end
            if CalibrationFlag == 1
                d = uiprogressdlg(app.UIFigure,'Title','Calibration','Message','Start');
                app.CalibAmp = 10^((app.CalibdB-app.SPL)/20)/sqrt(2);
                app.recObj = audiorecorder(app.Fs,16,1,app.InputID);
                record(app.recObj,10);
                pause(1)
                d.Value = 0;
                pause(1)
                d.Value = 0.1;
                pause(1)
                d.Value = 0.2;
                pause(1)
                d.Value = 0.3;
                pause(1)
                d.Value = 0.4;
                pause(1)
                d.Value = 0.5;
                pause(1)
                d.Value = 0.6;
                pause(1)
                d.Value = 0.7;
                pause(1)
                d.Value = 0.8;
                pause(1)
                d.Value = 0.9;
                pause(1)
                d.Value = 1.0;
                app.Xt = getaudiodata(app.recObj);
                app.CalibrationFactor = app.CalibAmp/rms(app.Xt);
                d.Message = 'Finish';
                pause(.5)
                close(d)
            end
        end

        % Value changed function: AudioINDropDown
        function AudioINDropDownValueChanged(app, event)
            app.Inputdevices = app.AudioINDropDown.Value;
            app.InputID = audiodevinfo(1,app.Inputdevices);
            app.DeviceReader = audioDeviceReader(app.Fs,app.Buffer,'Device',app.Inputdevices);
        end

        % Value changed function: AudioOUTDropDown
        function AudioOUTDropDownValueChanged(app, event)
            app.Outputdevices = app.AudioOUTDropDown.Value;
            app.OutputID = audiodevinfo(0,app.Outputdevices);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.VADButton.Enable = 0;
            app.StopButton.Enable = 1;
            app.StarttimeEditField.Enable = 0;
            app.StarttimeEditFieldLabel.Enable = 0;
            app.EndtimeEditField.Enable = 0;
            app.EndtimeEditFieldLabel.Enable = 0;
            app.PlayButton.Enable = 0;

            app.Lamp_Rec.Color = [1 0 0];
            app.Xt = [];
            Tindex = 0;
            setup(app.DeviceReader);
            tic;
            while app.StopFlag == 0
                audioData = app.DeviceReader();
                xin = audioData*app.CalibrationFactor;
                app.Xt   = [app.Xt; xin];
                Tindex = 1+Tindex;
                app.T = round((app.Buffer*Tindex)/app.Fs*100)/100;
                drawnow limitrate
                app.TimeLabel.Text  = ['Time: ' num2str(app.T) ' sec'];

                app.t = (0:length(app.Xt)-1)/app.Fs;
                plot(app.UIAxes,app.t,app.Xt)
                xlim(app.UIAxes,[app.t(1) app.t(end)])

                Lim = ceil(log10(max((xin).^2))+10);
                Lim(Lim<0) = 0;
                Lim(Lim>10) = 99;
                switch Lim
                    case 1
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 1 0];
                        app.Lamp_3.Color = [0 1 0];
                        app.Lamp_4.Color = [0 1 0];
                        app.Lamp_5.Color = [0 1 0];
                        app.Lamp_6.Color = [1 1 0];
                        app.Lamp_7.Color = [1 1 0];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 2
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 1 0];
                        app.Lamp_4.Color = [0 1 0];
                        app.Lamp_5.Color = [0 1 0];
                        app.Lamp_6.Color = [1 1 0];
                        app.Lamp_7.Color = [1 1 0];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 3
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 1 0];
                        app.Lamp_5.Color = [0 1 0];
                        app.Lamp_6.Color = [1 1 0];
                        app.Lamp_7.Color = [1 1 0];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 4
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 1 0];
                        app.Lamp_6.Color = [1 1 0];
                        app.Lamp_7.Color = [1 1 0];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 5
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 0 1];
                        app.Lamp_6.Color = [1 1 0];
                        app.Lamp_7.Color = [1 1 0];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 6
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 0 1];
                        app.Lamp_6.Color = [0 0 1];
                        app.Lamp_7.Color = [1 1 0];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 7
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 0 1];
                        app.Lamp_6.Color = [0 0 1];
                        app.Lamp_7.Color = [0 0 1];
                        app.Lamp_8.Color = [1 1 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 8
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 0 1];
                        app.Lamp_6.Color = [0 0 1];
                        app.Lamp_7.Color = [0 0 1];
                        app.Lamp_8.Color = [0 0 1];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                    case 9
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 0 1];
                        app.Lamp_6.Color = [0 0 1];
                        app.Lamp_7.Color = [0 0 1];
                        app.Lamp_8.Color = [0 0 1];
                        app.Lamp_9.Color = [0 0 1];
                        app.Lamp_10.Color = [1 0 0];
                    case 10
                        app.Lamp.Color = [0 0 1];
                        app.Lamp_2.Color = [0 0 1];
                        app.Lamp_3.Color = [0 0 1];
                        app.Lamp_4.Color = [0 0 1];
                        app.Lamp_5.Color = [0 0 1];
                        app.Lamp_6.Color = [0 0 1];
                        app.Lamp_7.Color = [0 0 1];
                        app.Lamp_8.Color = [0 0 1];
                        app.Lamp_9.Color = [0 0 1];
                        app.Lamp_10.Color = [0 0 1];
                    case 99
                        app.Lamp.Color = [1 0 0];
                        app.Lamp_2.Color = [1 0 0];
                        app.Lamp_3.Color = [1 0 0];
                        app.Lamp_4.Color = [1 0 0];
                        app.Lamp_5.Color = [1 0 0];
                        app.Lamp_6.Color = [1 0 0];
                        app.Lamp_7.Color = [1 0 0];
                        app.Lamp_8.Color = [1 0 0];
                        app.Lamp_9.Color = [1 0 0];
                        app.Lamp_10.Color = [1 0 0];
                end
            end
            toc
            release(app.DeviceReader);
            app.StopFlag = 0;
            app.Lamp.Color = [0 1 0];
            app.Lamp_2.Color = [0 1 0];
            app.Lamp_3.Color = [0 1 0];
            app.Lamp_4.Color = [0 1 0];
            app.Lamp_5.Color = [0 1 0];
            app.Lamp_6.Color = [1 1 0];
            app.Lamp_7.Color = [1 1 0];
            app.Lamp_8.Color = [1 1 0];
            app.Lamp_9.Color = [1 0 0];
            app.Lamp_10.Color = [1 0 0];
            UpdatePlot(app.CallingApp, app.Xt, app.Fs, app.t, app.Filename);
            app.StarttimeEditField.Value = app.t(1);
            app.STime = app.t(1);
            app.StartPoint = 1;
            app.EndtimeEditField.Value = app.t(end);
            app.ETime = app.t(end);
            app.EndPoint = length(app.t);
            app.Lamp_Rec.Color = [0 1 0];
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%             sound(app.Xt(app.StartPoint+1:app.EndPoint),app.Fs);
            app.player = audioplayer(app.Xt(app.StartPoint+1:app.EndPoint),app.Fs,16,app.OutputID);
            play(app.player);
            app.PlayFlag = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.PlayFlag == 1
                stop(app.player)
                app.PlayFlag = 0;
            else
                app.StopFlag = 1;
            end
            
            app.AudiowriteMenu.Enable = 1;
            app.PlayButton.Enable = 1;
            app.VADButton.Enable = 1;
            app.StopButton.Enable = 1;
            app.StarttimeEditField.Enable = 1;
            app.StarttimeEditFieldLabel.Enable = 1;
            app.EndtimeEditField.Enable = 1;
            app.EndtimeEditFieldLabel.Enable = 1;
        end

        % Button pushed function: VADButton
        function VADButtonPushed(app, event)
            [app.StartPoint, app.EndPoint] = VADForSQMsGUI(app.Xt,app.Threshold);
            app.STime = (app.StartPoint)/app.Fs;
            app.ETime = app.EndPoint/app.Fs;

            app.StarttimeEditField.Value = app.STime;
            app.EndtimeEditField.Value = app.ETime;

            plot(app.UIAxes,app.t,app.Xt)
            hold(app.UIAxes,'on')
            xline(app.UIAxes,app.STime,'LineWidth',2,'Color',[1 0 0])
            xline(app.UIAxes,app.ETime,'LineWidth',2,'Color',[1 0 0])
            hold(app.UIAxes,'off')

            app.VADXt = app.Xt(app.StartPoint+1:app.EndPoint);
            app.VADt = (0:length(app.VADXt)-1)/app.Fs;
            UpdatePlot(app.CallingApp, app.VADXt, app.Fs, app.VADt, app.Filename);
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            app.STime = app.StarttimeEditField.Value;
            if app.STime < app.t(1)
                app.STime = app.t(1);
                app.StarttimeEditField.Value = app.STime;
            end
            if app.STime >= app.ETime
                app.STime = app.t(1);
                app.StarttimeEditField.Value = app.STime;
            end
            plot(app.UIAxes,app.t,app.Xt)
            hold(app.UIAxes,'on')
            xline(app.UIAxes,app.STime,'LineWidth',2,'Color',[1 0 0])
            xline(app.UIAxes,app.ETime,'LineWidth',2,'Color',[1 0 0])
            hold(app.UIAxes,'off')

            app.StartPoint = round(app.STime*app.Fs);
            app.VADXt = app.Xt(app.StartPoint+1:app.EndPoint);
            app.VADt = (0:length(app.VADXt)-1)/app.Fs;
            UpdatePlot(app.CallingApp, app.VADXt, app.Fs, app.VADt, app.Filename);
        end

        % Value changed function: EndtimeEditField
        function EndtimeEditFieldValueChanged(app, event)
            app.ETime = app.EndtimeEditField.Value;
            if app.ETime > app.t(end)
                app.ETime = app.t(end);
                app.EndtimeEditField.Value = app.ETime;
            end
            if app.ETime <= app.STime
                app.ETime = app.t(end);
                app.EndtimeEditField.Value = app.ETime;
            end
            plot(app.UIAxes,app.t,app.Xt)
            hold(app.UIAxes,'on')
            xline(app.UIAxes,app.STime,'LineWidth',2,'Color',[1 0 0])
            xline(app.UIAxes,app.ETime,'LineWidth',2,'Color',[1 0 0])
            hold(app.UIAxes,'off')

            app.EndPoint = round(app.ETime*app.Fs);
            app.VADXt = app.Xt(app.StartPoint+1:app.EndPoint);
            app.VADt = (0:length(app.VADXt)-1)/app.Fs;
            UpdatePlot(app.CallingApp, app.VADXt, app.Fs, app.VADt, app.Filename);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.AudioReadMenu = uimenu(app.FileMenu);
            app.AudioReadMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioReadMenuSelected, true);
            app.AudioReadMenu.Text = 'Audio Read';
            app.AudiowriteMenu = uimenu(app.FileMenu);
            app.AudiowriteMenu.MenuSelectedFcn = createCallbackFcn(app, @AudiowriteMenuSelected, true);
            app.AudiowriteMenu.Text = 'Audio write';
            app.InportCalibrationDataMenu = uimenu(app.FileMenu);
            app.InportCalibrationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @InportCalibrationDataMenuSelected, true);
            app.InportCalibrationDataMenu.Text = 'Inport Calibration Data';
            app.ExportCalibrationDataMenu = uimenu(app.FileMenu);
            app.ExportCalibrationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCalibrationDataMenuSelected, true);
            app.ExportCalibrationDataMenu.Text = 'Export Calibration Data';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.VADsettingMenu = uimenu(app.UIFigure);
            app.VADsettingMenu.Text = 'VAD setting';
            app.ThresholdMenu = uimenu(app.VADsettingMenu);
            app.ThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @ThresholdMenuSelected, true);
            app.ThresholdMenu.Text = 'Threshold ';
            app.CalibrationMenu = uimenu(app.UIFigure);
            app.CalibrationMenu.Text = 'Calibration';
            app.SettingMenu = uimenu(app.CalibrationMenu);
            app.SettingMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingMenuSelected, true);
            app.SettingMenu.Text = 'Setting';
            app.CalibrationstartMenu = uimenu(app.CalibrationMenu);
            app.CalibrationstartMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationstartMenuSelected, true);
            app.CalibrationstartMenu.Text = 'Calibration start';
            app.Title = uipanel(app.UIFigure);
            app.RecordingLabel = uilabel(app.Title);
            app.RecordingLabel.Text = 'Recording';
            app.Image2 = uiimage(app.Title);
            app.Image2.ImageSource = 'Logo.png';
            app.AudioINDropDownLabel = uilabel(app.Title);
            app.AudioINDropDownLabel.Text = 'Audio IN';
            app.AudioINDropDown = uidropdown(app.Title);
            app.AudioINDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioINDropDownValueChanged, true);
            app.UnokiLABLabel = uilabel(app.Title);
            app.UnokiLABLabel.Text = 'Unoki-LAB';
            app.CreatedbyTakutoISOYAMALabel = uilabel(app.Title);
            app.CreatedbyTakutoISOYAMALabel.Text = 'Created by Takuto ISOYAMA';
            app.AudioOUTDropDownLabel = uilabel(app.Title);
            app.AudioOUTDropDownLabel.Text = 'Audio OUT';
            app.AudioOUTDropDown = uidropdown(app.Title);
            app.AudioOUTDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioOUTDropDownValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_2);
            app.Lamp = uilamp(app.Panel_3);
            app.Lamp_2 = uilamp(app.Panel_3);
            app.Lamp_3 = uilamp(app.Panel_3);
            app.Lamp_4 = uilamp(app.Panel_3);
            app.Lamp_5 = uilamp(app.Panel_3);
            app.Lamp_6 = uilamp(app.Panel_3);
            app.Lamp_7 = uilamp(app.Panel_3);
            app.Lamp_8 = uilamp(app.Panel_3);
            app.Lamp_9 = uilamp(app.Panel_3);
            app.Lamp_10 = uilamp(app.Panel_3);
            app.Lamp_11 = uilamp(app.Panel_3);
            app.PlayButton = uibutton(app.Panel_2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'Play.png';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.Panel_2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'Stop.png';
            app.StopButton.Text = 'Stop';
            app.Lamp_Rec = uilamp(app.Panel_2);
            app.RecordButton = uibutton(app.Panel_2, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Icon = 'Record.png';
            app.RecordButton.Text = 'Rec.';
            app.TimeLabel = uilabel(app.Panel_2);
            app.TimeLabel.Text = 'Time: ';
            app.VADButton = uibutton(app.Panel_2, 'push');
            app.VADButton.ButtonPushedFcn = createCallbackFcn(app, @VADButtonPushed, true);
            app.VADButton.Icon = 'VAD.png';
            app.VADButton.Text = 'VAD';
            app.Title_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Title_2);
            xlabel(app.UIAxes, 'Time [sec]')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.StarttimeEditFieldLabel = uilabel(app.Title_2);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.Title_2, 'numeric');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.EndtimeEditFieldLabel = uilabel(app.Title_2);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.Title_2, 'numeric');
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recorder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end