classdef SQMsApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        AudioReadMenu                matlab.ui.container.Menu
        AudioWriteMenu               matlab.ui.container.Menu
        CloseMenu                    matlab.ui.container.Menu
        RecordMenu                   matlab.ui.container.Menu
        RecordingMenu                matlab.ui.container.Menu
        SQMssettingMenu              matlab.ui.container.Menu
        AuditoryfilterbankMenu       matlab.ui.container.Menu
        GammatoneMenu                matlab.ui.container.Menu
        GammachirpMenu               matlab.ui.container.Menu
        FieldMenu                    matlab.ui.container.Menu
        FreeMenu                     matlab.ui.container.Menu
        DiffuseMenu                  matlab.ui.container.Menu
        Panel_3                      matlab.ui.container.Panel
        SpecificFluctuation          matlab.ui.control.UIAxes
        SpecificRoughness            matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        SpecificLoudness             matlab.ui.control.UIAxes
        Panel_2                      matlab.ui.container.Panel
        PlayButton                   matlab.ui.control.Button
        SPLEditField                 matlab.ui.control.NumericEditField
        SPLEditFieldLabel            matlab.ui.control.Label
        FluctuationCheckBox          matlab.ui.control.CheckBox
        RoughnessCheckBox            matlab.ui.control.CheckBox
        SharpnessCheckBox            matlab.ui.control.CheckBox
        LoudnessCheckBox             matlab.ui.control.CheckBox
        AnalysisButton               matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        vacilLabel                   matlab.ui.control.Label
        asperLabel                   matlab.ui.control.Label
        acumLabel                    matlab.ui.control.Label
        soneLabel                    matlab.ui.control.Label
        FluctuationstrengthLabel     matlab.ui.control.Label
        RoughnessLabel               matlab.ui.control.Label
        SharpnessLabel               matlab.ui.control.Label
        LoudnessLabel                matlab.ui.control.Label
        MeanFluctuation              matlab.ui.control.NumericEditField
        MeanRoughness                matlab.ui.control.NumericEditField
        MeanSharpness                matlab.ui.control.NumericEditField
        MeanLoudness                 matlab.ui.control.NumericEditField
        SoundqualitymetricsLabel     matlab.ui.control.Label
        Title                        matlab.ui.container.Panel
        CreatedbyTakutoISOYAMALabel  matlab.ui.control.Label
        UnokiLABLabel                matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        SQMsAnalysisLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description
        RecordingApp
        Xt
        Xt0
        Fs = 44100;
        Fs0
        t
        T
        Setting
        SPL = 100;
        Audioname
        Filename = 0;
        Filepath
        Tool
        Flag
        %% SQMs
        % Loudness
        Nt
        N
        Npk
        OverallLoudness
        % Sharpness
        St
        S
        OverallSharpness
        % Roughness
        Rt
        R
        Rpk
        OverallRoughness
        % Fluctuation
        Ft
        FS
        Fpk
        OverallFluctuation
    end

    methods (Access = public)

        function UpdatePlot(app, Xt, Fs, t, Audioname)
            app.Xt = Xt;
            app.t = t;
            app.Fs = Fs;
            app.Audioname = Audioname;
            app.T = length(app.Xt)/app.Fs*1000;
            
            plot(app.UIAxes,app.t,app.Xt)
            xlim(app.UIAxes,[app.t(1) app.t(end)])

            app.SharpnessCheckBox.Value = 0;
            app.RoughnessCheckBox.Value = 0;
            app.FluctuationCheckBox.Value = 0;

            app.Setting.Sharpness = 'OFF';
            app.Setting.Roughness = 'OFF';
            app.Setting.Fluctuation = 'OFF';

            app.MeanLoudness.Value = 0;
            app.MeanSharpness.Value = 0;
            app.MeanRoughness.Value = 0;
            app.MeanFluctuation.Value = 0;

            app.PlayButton.Enable = 1;
            app.AnalysisButton.Enable = 1;
            app.SPLEditField.Enable = 1;
            app.LoudnessCheckBox.Enable = 0;
            app.SharpnessCheckBox.Enable = 1;
            app.RoughnessCheckBox.Enable = 1;
            app.FluctuationCheckBox.Enable = 1;

            plot(app.SpecificLoudness,0,0,'LineWidth',2)
            plot(app.SpecificRoughness,0,0,'LineWidth',2)
            plot(app.SpecificFluctuation,0,0,'LineWidth',2)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Setting.AF = 'Gammatone';
            app.Setting.Field = 'Free';
            app.Setting.Figshow = 'OFF';
            app.Setting.ERBNnumber = 1.8:0.1:38.9;
            app.Setting.Parallel = 'ON';
            app.Setting.Op = 'Ap';
            app.Setting.SPLmode = 'Signal';
            app.Setting.TimeVarying = 'OFF';

            app.Setting.Loudness = 'ON';
            app.Setting.Sharpness = 'OFF';
            app.Setting.Roughness = 'OFF';
            app.Setting.Fluctuation = 'OFF';

            app.LoudnessCheckBox.Value = 1;
            app.SharpnessCheckBox.Value = 0;
            app.RoughnessCheckBox.Value = 0;
            app.FluctuationCheckBox.Value = 0;
            
            app.GammatoneMenu.Enable = 0;
            app.FreeMenu.Enable = 0;
            app.SaveMenu.Enable = 0;
            app.AudioWriteMenu.Enable = 0;

            app.LoudnessCheckBox.Enable = 0;
            app.SharpnessCheckBox.Enable = 0;
            app.RoughnessCheckBox.Enable = 0;
            app.FluctuationCheckBox.Enable = 0;
            app.PlayButton.Enable = 0;
            app.SPLEditField.Enable = 0;
            app.AnalysisButton.Enable = 0;

            % Parallel
            app.Tool = ver;
            app.Flag = 0;
            for k=1:length(app.Tool)
                if strcmp(app.Tool(k).Name,'Parallel Computing Toolbox')==1
                    app.Flag = 1;
                end
            end
            if app.Flag == 0
                app.ParallelDropDown.Enable = 0;
                app.Setting.Parallel = 'OFF';
            end

            app.SPLEditField.Value = app.SPL;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %% Loudness
            SQM.Loudness.Nt = app.Nt;
            SQM.Loudness.MeanN = app.OverallLoudness;
            SQM.Loudness.N = app.N;
            SQM.Loudness.Npk = app.Npk;
            SQM.Loudness.NFs = 44100;

            %% Sharpness
            if strcmp(app.Setting.Sharpness,'ON') == 1
                SQM.Sharpness.St = app.St;
                SQM.Sharpness.MeanS = app.OverallSharpness;
                SQM.Sharpness.S = app.S;
            end

            %% Roughness
            if strcmp(app.Setting.Roughness,'ON') == 1
                SQM.Roughness.Rt = app.Rt;
                SQM.Roughness.MeanR = app.OverallRoughness;
                SQM.Roughness.R = app.R;
                SQM.Roughness.Rpk = app.Rpk;
                SQM.Roughness.RFs = 1000;
            end

            %% Fluctuation strength
            if strcmp(app.Setting.Fluctuation,'ON') == 1
                SQM.Flcutauation.Ft = app.Ft;
                SQM.Flcutauation.MeanF = app.OverallFlcutauation;
                SQM.Flcutauation.FS = app.FS;
                SQM.Flcutauation.Fpk = app.Fpk;
                SQM.Flcutauation.FFs = 200;
            end

            %% Audio info
            AudioInf.SPL = app.SPL;
            AudioInf.Xt = app.Xt;
            AudioInf.Fs = app.Fs;
            AudioInf.t = app.t;
            AudioInf.T = app.T;
            if isempty(app.Audioname) == 1
                tt = datetime('now','Locale','system','Format','yyyy-MM-dd''T''HH:mmXXX');
                AudioInf.Name = ['Record_' char(tt) '.wav'];
            else
                AudioInf.Name = app.Audioname;
            end

            %% Setting
            Parameter = app.Setting;
            app.Filename = 0;
            [app.Filename,app.Filepath] = uiputfile('*.mat','Save mat file','Output.mat');
            if app.Filename ~= 0
                % PopUp
                d = uiprogressdlg(app.UIFigure,'Title','Saving','Indeterminate','on');
                drawnow
                save([app.Filepath app.Filename],'SQM','Parameter','AudioInf');
                close(d)
            else
                uialert(app.UIFigure,'Please input file name','No file name')
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.Filename = 0;
            plot(app.SpecificLoudness,0,0,'LineWidth',2)
            plot(app.SpecificRoughness,0,0,'LineWidth',2)
            plot(app.SpecificFluctuation,0,0,'LineWidth',2)
            [app.Filename,app.Filepath] = uigetfile('*.mat','File Selector');
            if app.Filename ~= 0
                app.MeanLoudness.Value = 0;
                app.MeanSharpness.Value = 0;
                app.MeanRoughness.Value = 0;
                app.MeanFluctuation.Value = 0;

                app.SharpnessCheckBox.Value = 0;
                app.RoughnessCheckBox.Value = 0;
                app.FluctuationCheckBox.Value = 0;

                app.SharpnessCheckBox.Enable = 1;
                app.RoughnessCheckBox.Enable = 1;
                app.FluctuationCheckBox.Enable = 1;
                
                disp([app.Filepath app.Filename])
                d = uiprogressdlg(app.UIFigure,'Title','Now loading','Indeterminate','on');
                drawnow
                load([app.Filepath app.Filename],'Parameter','SQM','AudioInf')
                close(d)

                app.Setting = Parameter;
                app.Xt = AudioInf.Xt;
                app.Fs = AudioInf.Fs;
                app.SPL = AudioInf.SPL;
                app.t = AudioInf.t;
                app.T = AudioInf.T;

                %% Loudness
                app.Nt = SQM.Loudness.Nt;
                app.OverallLoudness = SQM.Loudness.MeanN;
                app.N = SQM.Loudness.N;
                app.Npk = SQM.Loudness.Npk;
                app.MeanLoudness.Value = app.OverallLoudness;
                plot(app.SpecificLoudness,app.Setting.ERBNnumber,mean(app.Npk(:,0.2*app.Fs+1:end-0.2*app.Fs),2),'LineWidth',2)
                xlim(app.SpecificLoudness,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])

                %% Sharpness
                if strcmp(app.Setting.Sharpness,'ON') == 1
                    app.St = SQM.Sharpness.St;
                    app.OverallSharpness = SQM.Sharpness.MeanS;
                    app.S = SQM.Sharpness.S;
                    app.SharpnessCheckBox.Value = 1;
                    app.MeanSharpness.Value = app.OverallSharpness;
                end

                %% Roughness
                if strcmp(app.Setting.Roughness,'ON') == 1
                    app.Rt = SQM.Roughness.Rt;
                    app.OverallRoughness = SQM.Roughness.MeanR;
                    app.R = SQM.Roughness.R;
                    app.Rpk = SQM.Roughness.Rpk;
                    app.RoughnessCheckBox.Value = 1;
                    app.MeanRoughness.Value = app.OverallRoughness;
                    plot(app.SpecificRoughness,app.Setting.ERBNnumber,mean(app.Rpk(:,0.2*1000+1:end-0.2*1000),2),'LineWidth',2)
                    xlim(app.SpecificRoughness,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                end

                %% Fluctuation strength
                if strcmp(app.Setting.Fluctuation,'ON') == 1
                    app.Ft = SQM.Flcutauation.Ft;
                    app.OverallFlcutauation = SQM.Flcutauation.MeanF;
                    app.FS = SQM.Flcutauation.FS;
                    app.Fpk = SQM.Flcutauation.Fpk;
                    app.FluctuationCheckBox.Value = 1;
                    app.MeanFluctuation.Value = app.OverallFlcutauation;
                    plot(app.SpecificFluctuation,app.Setting.ERBNnumber,mean(app.Fpk(:,200+1:end-200),2),'LineWidth',2)
                    xlim(app.SpecificFluctuation,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                end

                plot(app.UIAxes,app.t,app.Xt)
                xlim(app.UIAxes,[app.t(1) app.t(end)])

                app.SPLEditField.Value = app.SPL;

                app.PlayButton.Enable = 1;
                app.AnalysisButton.Enable = 1;
                app.SPLEditField.Enable = 1;
                app.SaveMenu.Enable = 1;
                app.AudioWriteMenu.Enable = 1;
            end
        end

        % Menu selected function: AudioReadMenu
        function AudioReadMenuSelected(app, event)
            app.Audioname = 0;
            [app.Audioname,app.Filepath] = uigetfile({'*.wav';'*.mp3';'*.m4a';'*.mp4';'*.aifc';...
                '*.aiff';'*.aif';'*.au';'*.flac';'*.ogg';'*.opus'},'File Selector');
            if app.Audioname ~= 0
                plot(app.SpecificLoudness,0,0,'LineWidth',2)
                plot(app.SpecificRoughness,0,0,'LineWidth',2)
                plot(app.SpecificFluctuation,0,0,'LineWidth',2)
                disp([app.Filepath app.Audioname])
                [app.Xt0,app.Fs0] = audioread([app.Filepath app.Audioname]);
                app.Xt = resample(app.Xt0,app.Fs,app.Fs0);
                app.t = (0:length(app.Xt)-1)/app.Fs;
                app.T = length(app.Xt)/app.Fs*1000;

                plot(app.UIAxes,app.t,app.Xt)
                xlim(app.UIAxes,[0 app.t(end)])

                app.SPL = 100;
                app.SPLEditField.Value = app.SPL;

                app.SharpnessCheckBox.Value = 0;
                app.RoughnessCheckBox.Value = 0;
                app.FluctuationCheckBox.Value = 0;

                app.Setting.Sharpness = 'OFF';
                app.Setting.Roughness = 'OFF';
                app.Setting.Fluctuation = 'OFF';

                app.MeanLoudness.Value = 0;
                app.MeanSharpness.Value = 0;
                app.MeanRoughness.Value = 0;
                app.MeanFluctuation.Value = 0;

                app.PlayButton.Enable = 1;
                app.AnalysisButton.Enable = 1;
                app.SPLEditField.Enable = 1;
                app.LoudnessCheckBox.Enable = 0;
                app.SharpnessCheckBox.Enable = 1;
                app.RoughnessCheckBox.Enable = 1;
                app.FluctuationCheckBox.Enable = 1;
                app.SaveMenu.Enable = 0;
                app.AudioWriteMenu.Enable = 1;
            end
        end

        % Menu selected function: AudioWriteMenu
        function AudioWriteMenuSelected(app, event)
            app.Audioname = 0;
            [app.Audioname,app.Filepath] = uiputfile('*.wav','Audio write','Output.wav');
            if app.Audioname ~= 0
                audiowrite([app.Filepath app.Audioname],app.Xt,app.Fs);
            else
                uialert(app.UIFigure,'Please input file name','No file name')
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: GammatoneMenu
        function GammatoneMenuSelected(app, event)
            app.Setting.AF = 'Gammatone';
            app.Setting.ERBNnumber = 1.8:0.1:38.9;
            app.GammatoneMenu.Enable = 0;
            app.GammachirpMenu.Enable = 1;
        end

        % Menu selected function: GammachirpMenu
        function GammachirpMenuSelected(app, event)
            app.Setting.AF = 'Gammachirp';
            app.Setting.ERBNnumber = 2.6:0.1:38.9;
            app.GammatoneMenu.Enable = 1;
            app.GammachirpMenu.Enable = 0;
        end

        % Menu selected function: FreeMenu
        function FreeMenuSelected(app, event)
            app.Setting.Field = 'Free';
            app.FreeMenu.Enable = 0;
            app.DiffuseMenu.Enable = 1;
        end

        % Menu selected function: DiffuseMenu
        function DiffuseMenuSelected(app, event)
            app.Setting.Field = 'Diffuse';
            app.FreeMenu.Enable = 1;
            app.DiffuseMenu.Enable = 0;
        end

        % Menu selected function: RecordingMenu
        function RecordingMenuSelected(app, event)
            app.RecordingApp = Recorder(app);
            app.SaveMenu.Enable = 0;
            app.AudioWriteMenu.Enable = 1;
        end

        % Value changed function: SharpnessCheckBox
        function SharpnessCheckBoxValueChanged(app, event)
            SharpnessCheck = app.SharpnessCheckBox.Value;
            if SharpnessCheck == 1
                app.Setting.Sharpness = 'ON';
            else
                app.Setting.Sharpness = 'OFF';
            end
            disp(['Sharpness: ' app.Setting.Sharpness])
        end

        % Value changed function: RoughnessCheckBox
        function RoughnessCheckBoxValueChanged(app, event)
            if 200 <= app.T
                RoughnessCheck = app.RoughnessCheckBox.Value;
                if RoughnessCheck == 1
                    app.Setting.Roughness = 'ON';
                else
                    app.Setting.Roughness = 'OFF';
                end
            else
                app.RoughnessCheckBox.Value = 0;
                uialert(app.UIFigure,'Signal length is insufficient. It must be at least 200 ms to be analyzed.','Dulation Error');
            end
            disp(['Roughness: ' app.Setting.Roughness])
        end

        % Value changed function: FluctuationCheckBox
        function FluctuationCheckBoxValueChanged(app, event)
            if 4000 <= app.T
                FluctuationCheck = app.FluctuationCheckBox.Value;
                if FluctuationCheck == 1
                    app.Setting.Fluctuation = 'ON';
                else
                    app.Setting.Fluctuation = 'OFF';
                end
            else
                app.FluctuationCheckBox.Value = 0;
                uialert(app.UIFigure,'Signal length is insufficient. It must be at least 4,000 ms to be analyzed.','Dulation Error');
            end
            disp(['Fluctuation strength: ' app.Setting.Fluctuation])
        end

        % Value changed function: SPLEditField
        function SPLEditFieldValueChanged(app, event)
            app.SPL = app.SPLEditField.Value;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.Xt,app.Fs)
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            plot(app.SpecificLoudness,0,0,'LineWidth',2)
            plot(app.SpecificRoughness,0,0,'LineWidth',2)
            plot(app.SpecificFluctuation,0,0,'LineWidth',2)
            switch app.Setting.AF
                case 'Gammatone'
                    %% Loudness
                    % PopUp
                    d = uiprogressdlg(app.UIFigure,'Title','Calculation of loudness','Indeterminate','on');
                    drawnow
                    %
                    [app.Nt,app.N,app.Npk,~,~] = GTLoudnessModel(app.Xt,app.Fs,app.SPL,app.Setting);
                    app.OverallLoudness = mean(app.N(1,0.2*app.Fs+1:end-0.2*app.Fs));
                    app.MeanLoudness.Value = app.OverallLoudness;
                    plot(app.SpecificLoudness,app.Setting.ERBNnumber,mean(app.Npk(:,0.2*app.Fs+1:end-0.2*app.Fs),2),'LineWidth',2)
                    xlim(app.SpecificLoudness,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                    close(d)

                    %% Sharpness
                    if strcmp(app.Setting.Sharpness,'ON') == 1
                        % PopUp
                        d = uiprogressdlg(app.UIFigure,'Title','Calculation of sharpness','Indeterminate','on');
                        drawnow
                        %
                        [app.St,app.S] = GTSharpnessModel(app.Npk,app.N,app.Fs,app.Setting);
                        app.OverallSharpness = mean(app.S(1,0.2*app.Fs+1:end-0.2*app.Fs));
                        app.MeanSharpness.Value = app.OverallSharpness;
                        close(d)
                    end

                    %% Roughness
                    if strcmp(app.Setting.Roughness,'ON') == 1
                        % PopUp
                        d = uiprogressdlg(app.UIFigure,'Title','Calculation of roughness','Indeterminate','on');
                        drawnow
                        %
                        [app.Rt,app.R,app.Rpk,~,~] = GTRoughnessModel(app.Npk,app.Fs,app.Setting);
                        app.OverallRoughness = mean(app.R(1,0.2*1000+1:end-0.2*1000));
                        app.MeanRoughness.Value = app.OverallRoughness;
                        plot(app.SpecificRoughness,app.Setting.ERBNnumber,mean(app.Rpk(:,0.2*1000+1:end-0.2*1000),2),'LineWidth',2)
                        xlim(app.SpecificRoughness,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                        close(d)
                    end

                    %% Fluctuation  strength
                    if strcmp(app.Setting.Fluctuation,'ON') == 1
                        % PopUp
                        d = uiprogressdlg(app.UIFigure,'Title','Calculation of fluctuation strength','Indeterminate','on');
                        drawnow
                        %
                        [app.Ft,app.FS,app.Fpk,~,~] = GTFluctuationStrengthModel(app.Npk,app.Fs,app.Setting);
                        app.OverallFluctuation = mean(app.FS(1,200+1:end-200));
                        app.MeanFluctuation.Value = app.OverallFluctuation;
                        plot(app.SpecificFluctuation,app.Setting.ERBNnumber,mean(app.Fpk(:,200+1:end-200),2),'LineWidth',2)
                        xlim(app.SpecificFluctuation,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                        close(d)
                    end

                case 'Gammachirp'
                    %% Loudness
                    % PopUp
                    d = uiprogressdlg(app.UIFigure,'Title','Calculation of loudness','Indeterminate','on');
                    drawnow
                    %
                    [app.Nt,app.N,app.Npk,~,~] = GCLoudnessModel(app.Xt,app.Fs,app.SPL,app.Setting);
                    app.OverallLoudness = mean(app.N(1,0.2*app.Fs+1:end-0.2*app.Fs));
                    app.MeanLoudness.Value = app.OverallLoudness;
                    plot(app.SpecificLoudness,app.Setting.ERBNnumber,mean(app.Npk(:,0.2*app.Fs+1:end-0.2*app.Fs),2),'LineWidth',2)
                    xlim(app.SpecificLoudness,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                    close(d)

                    %% Sharpness
                    if strcmp(app.Setting.Sharpness,'ON') == 1
                        % PopUp
                        d = uiprogressdlg(app.UIFigure,'Title','Calculation of sharpness','Indeterminate','on');
                        drawnow
                        %
                        [app.St,app.S] = GCSharpnessModel(app.Npk,app.N,app.Fs,app.Setting);
                        app.OverallSharpness = mean(app.S(1,0.2*app.Fs+1:end-0.2*app.Fs));
                        app.MeanSharpness.Value = app.OverallSharpness;
                        close(d)
                    end

                    %% Roughness
                    if strcmp(app.Setting.Roughness,'ON') == 1
                        % PopUp
                        d = uiprogressdlg(app.UIFigure,'Title','Calculation of roughness','Indeterminate','on');
                        drawnow
                        %
                        [app.Rt,app.R,app.Rpk,~,~] = GCRoughnessModel(app.Npk,app.Fs,app.Setting);
                        app.OverallRoughness = mean(app.R(1,0.2*1000+1:end-0.2*1000));
                        app.MeanRoughness.Value = app.OverallRoughness;
                        plot(app.SpecificRoughness,app.Setting.ERBNnumber,mean(app.Rpk(:,0.2*1000+1:end-0.2*1000),2),'LineWidth',2)
                        xlim(app.SpecificRoughness,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                        close(d)
                    end

                    %% Fluctuation strength
                    if strcmp(app.Setting.Fluctuation,'ON') == 1
                        % PopUp
                        d = uiprogressdlg(app.UIFigure,'Title','Calculation of fluctuation strength','Indeterminate','on');
                        drawnow
                        %
                        [app.Ft,app.FS,app.Fpk,~,~] = GCFluctuationStrengthModel(app.Npk,app.Fs,app.Setting);
                        app.OverallFluctuation = mean(app.FS(1,0.2*200+1:end-0.2*200));
                        app.MeanFluctuation.Value = app.OverallFluctuation;
                        plot(app.SpecificFluctuation,app.Setting.ERBNnumber,mean(app.Fpk(:,0.2*200+1:end-0.2*200),2),'LineWidth',2)
                        xlim(app.SpecificFluctuation,[app.Setting.ERBNnumber(1,1) app.Setting.ERBNnumber(end)])
                        close(d)
                    end
            end
            app.SaveMenu.Enable = 1;
            app.AudioWriteMenu.Enable = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.AudioReadMenu = uimenu(app.FileMenu);
            app.AudioReadMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioReadMenuSelected, true);
            app.AudioReadMenu.Accelerator = 'r';
            app.AudioReadMenu.Text = 'Audio Read';
            app.AudioWriteMenu = uimenu(app.FileMenu);
            app.AudioWriteMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioWriteMenuSelected, true);
            app.AudioWriteMenu.Accelerator = 'a';
            app.AudioWriteMenu.Text = 'Audio Write';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.RecordMenu = uimenu(app.UIFigure);
            app.RecordMenu.Text = 'Record';
            app.RecordingMenu = uimenu(app.RecordMenu);
            app.RecordingMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordingMenuSelected, true);
            app.RecordingMenu.Text = 'Recording';
            app.SQMssettingMenu = uimenu(app.UIFigure);
            app.SQMssettingMenu.Text = 'SQMs setting';
            app.AuditoryfilterbankMenu = uimenu(app.SQMssettingMenu);
            app.AuditoryfilterbankMenu.Text = 'Auditory filterbank';
            app.GammatoneMenu = uimenu(app.AuditoryfilterbankMenu);
            app.GammatoneMenu.MenuSelectedFcn = createCallbackFcn(app, @GammatoneMenuSelected, true);
            app.GammatoneMenu.Text = 'Gammatone';
            app.GammachirpMenu = uimenu(app.AuditoryfilterbankMenu);
            app.GammachirpMenu.MenuSelectedFcn = createCallbackFcn(app, @GammachirpMenuSelected, true);
            app.GammachirpMenu.Text = 'Gammachirp';
            app.FieldMenu = uimenu(app.SQMssettingMenu);
            app.FieldMenu.Text = 'Field';
            app.FreeMenu = uimenu(app.FieldMenu);
            app.FreeMenu.MenuSelectedFcn = createCallbackFcn(app, @FreeMenuSelected, true);
            app.FreeMenu.Text = 'Free';
            app.DiffuseMenu = uimenu(app.FieldMenu);
            app.DiffuseMenu.MenuSelectedFcn = createCallbackFcn(app, @DiffuseMenuSelected, true);
            app.DiffuseMenu.Text = 'Diffuse';
            app.Title = uipanel(app.UIFigure);
            app.SQMsAnalysisLabel = uilabel(app.Title);
            app.SQMsAnalysisLabel.Text = 'SQMs Analysis';
            app.Image2 = uiimage(app.Title);
            app.Image2.ImageSource = 'Logo.png';
            app.UnokiLABLabel = uilabel(app.Title);
            app.UnokiLABLabel.Text = 'Unoki-LAB';
            app.CreatedbyTakutoISOYAMALabel = uilabel(app.Title);
            app.CreatedbyTakutoISOYAMALabel.Text = 'Created by Takuto ISOYAMA';
            app.Panel = uipanel(app.UIFigure);
            app.SoundqualitymetricsLabel = uilabel(app.Panel);
            app.SoundqualitymetricsLabel.Text = 'Sound quality metrics';
            app.MeanLoudness = uieditfield(app.Panel, 'numeric');
            app.MeanLoudness.Editable = 'off';
            app.MeanSharpness = uieditfield(app.Panel, 'numeric');
            app.MeanSharpness.Editable = 'off';
            app.MeanRoughness = uieditfield(app.Panel, 'numeric');
            app.MeanRoughness.Editable = 'off';
            app.MeanFluctuation = uieditfield(app.Panel, 'numeric');
            app.MeanFluctuation.Editable = 'off';
            app.LoudnessLabel = uilabel(app.Panel);
            app.LoudnessLabel.Text = 'Loudness';
            app.SharpnessLabel = uilabel(app.Panel);
            app.SharpnessLabel.Text = 'Sharpness';
            app.RoughnessLabel = uilabel(app.Panel);
            app.RoughnessLabel.Text = 'Roughness';
            app.FluctuationstrengthLabel = uilabel(app.Panel);
            app.FluctuationstrengthLabel.Text = 'Fluctuation strength';
            app.soneLabel = uilabel(app.Panel);
            app.soneLabel.Text = 'sone';
            app.acumLabel = uilabel(app.Panel);
            app.acumLabel.Text = 'acum';
            app.asperLabel = uilabel(app.Panel);
            app.asperLabel.Text = 'asper';
            app.vacilLabel = uilabel(app.Panel);
            app.vacilLabel.Text = 'vacil';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AnalysisButton = uibutton(app.Panel_2, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.LoudnessCheckBox = uicheckbox(app.Panel_2);
            app.LoudnessCheckBox.Enable = 'off';
            app.LoudnessCheckBox.Text = 'Loudness';
            app.LoudnessCheckBox.Value = true;
            app.SharpnessCheckBox = uicheckbox(app.Panel_2);
            app.SharpnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @SharpnessCheckBoxValueChanged, true);
            app.SharpnessCheckBox.Text = 'Sharpness';
            app.RoughnessCheckBox = uicheckbox(app.Panel_2);
            app.RoughnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @RoughnessCheckBoxValueChanged, true);
            app.RoughnessCheckBox.Text = 'Rougness';
            app.FluctuationCheckBox = uicheckbox(app.Panel_2);
            app.FluctuationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FluctuationCheckBoxValueChanged, true);
            app.FluctuationCheckBox.Text = 'Fluctuation strength';
            app.SPLEditFieldLabel = uilabel(app.Panel_2);
            app.SPLEditFieldLabel.Text = 'SPL';
            app.SPLEditField = uieditfield(app.Panel_2, 'numeric');
            app.SPLEditField.ValueChangedFcn = createCallbackFcn(app, @SPLEditFieldValueChanged, true);
            app.SPLEditField.Value = 100;
            app.PlayButton = uibutton(app.Panel_2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SpecificLoudness = uiaxes(app.Panel_3);
            title(app.SpecificLoudness, 'Specific loudness')
            xlabel(app.SpecificLoudness, 'ERB_N-number [Cam]')
            ylabel(app.SpecificLoudness, 'Specific loudness [sone/ERB]')
            zlabel(app.SpecificLoudness, 'Z')
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, 'Time [sec]')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.SpecificRoughness = uiaxes(app.Panel_3);
            title(app.SpecificRoughness, 'Specific roughness')
            xlabel(app.SpecificRoughness, 'ERB_N-number [Cam]')
            ylabel(app.SpecificRoughness, 'Specific roughness [asper/ERB]')
            zlabel(app.SpecificRoughness, 'Z')
            app.SpecificFluctuation = uiaxes(app.Panel_3);
            title(app.SpecificFluctuation, 'Specific fluctuation strength')
            xlabel(app.SpecificFluctuation, 'ERB_N-number [Cam]')
            ylabel(app.SpecificFluctuation, 'Specific fluctuation-strength [vacil/ERB]')
            zlabel(app.SpecificFluctuation, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SQMsApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end